classdef myclassifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UITable            matlab.ui.control.Table
        UIAxes             matlab.ui.control.UIAxes
        Lamp               matlab.ui.control.Lamp
        RunButton          matlab.ui.control.StateButton
        TextArea           matlab.ui.control.TextArea
        featparamDropDown  matlab.ui.control.DropDown
        LoadButton         matlab.ui.control.Button
        Switch             matlab.ui.control.ToggleSwitch
        ListBox            matlab.ui.control.ListBox
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes_3           matlab.ui.control.UIAxes
        UIAxes_4           matlab.ui.control.UIAxes
        Slider             matlab.ui.control.Slider
    end


    properties (Access = public)
        allfiles  % Description
    end

    properties (Access = private)
        button % Description
        filename % Description
        pathname % Description
        s % Description
    end


    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            promptMessage = sprintf('Which dataset do you want to use?');
            app.button = questdlg(promptMessage, 'Dataset', 'Dataset 1', 'Dataset 2',...
                                'Dataset 3', 'Cancel');
            if strcmp(app.button, 'Cancel')
                return;
            end
            
            
            [~, app.pathname]  = uigetfile('*.*', 'Specify an image file'); 
            app.allfiles = dir( fullfile(app.pathname,'*.png') );%# list all *.xyz files
            app.allfiles = {app.allfiles.name}'; %'# file names   
            
            
            img_combined = [];
            Table = [];
            switch app.button
            % Separate dataset 1 into training and testing set
                   case 'Dataset 1'        
                   % reshape cell into 100 by 3 images
                   newshape = reshape(app.allfiles,[100,3]);
                   % Assign 15% of the data as testing set
                   testset = newshape(1:15, :);
                   % Assign 85% of data as testing set    
                   trainset = newshape(16:end,:);
        
            % Separate dataset 2 into training and testing set
                   case 'Dataset 2'
                   % reshape cell into 100 by 16 images
                   newshape = reshape(app.allfiles,[16,100]);
                   % Assign 15% of the data as testing set
                   testset = newshape(:,1:15);
                   % Assign 85% of data as testing set    
                   trainset = newshape(:,16:end);
            % Separate dataset 3 into training and testing set
                   case 'Dataset 3'
                   % reshape cell into 59 by 16 images
                   newshape = reshape(app.allfiles,[16,59]);
                   % Assign 15% of the data as testing set
                   testset = newshape(:,1:9);
                   % Assign 85% of data as testing set    
                   trainset = newshape(:,10:end);
             end 
            % 
            %                     image1 = imread(app.allfiles);
            %                     imshow(image1, 'Parent', app.UIAxes);
            %                     app.TextArea.Value = string(trainset);
            
            app.RunButton.Enable = 'on';
            app.ListBox.Enable = 'on';
            app.Slider.Enable = 'on';
            app.ListBox.Items =  app.allfiles;    
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
          
            if  isequal(value,'Train')
                app.Lamp.Enable = 'on';
                app.ListBox.Enable = 'off';
            elseif isequal(value,'Test')
                app.Lamp.Enable = 'off';
                app.ListBox.Enable = 'on';
            end
            %             app.LoadButton.Enable = 'on';
           
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            global image1 image2 image3 
            
            app.ListBox.Enable = 'on';
            app.ListBox.Items =  app.allfiles;
            %% Testing phase
            % Define value as what useR selects from the listbox
            value = app.ListBox.Value;
            
            % Display raw image in top left figure
            image1 = imread(strcat(app.pathname,value));            
            imshow(image1, 'Parent', app.UIAxes);
            
            % Normalize the image selected by user
            image2 = normal3(image1);
            image3 = enhance(image2.img);
            
            % Display Normalized image in the top right figure
            imshow(image3.img_histeq, 'Parent', app.UIAxes_2);
            
            % Options include:
%              img_imadjust: [512×512×3 double]
%              img_histeq: [512×512×3 double]
%              img_adapthisteq: [512×512×3 double] 
%             [BW,maskedRGBImage] = createMask(image3.img_adapthisteq);
%             s0 = 0;
%             app.s = app.Slider.Value;
%             while abs(s0 - app.s) > 0
%             app.s = app.Slider.Value;
%             [BW , maskedImage] = segmentation(image3.img_histeq, app.s);
%             imshow(BW, 'Parent', app.UIAxes_3);
%             imshow(maskedImage, 'Parent', app.UIAxes_4);
%             s0 = app.s;
%             end
%             %% Training Phase
                        
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.ListBox.Value;
            app.TextArea.Value = value;
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.TextArea.Value = value;
                         app.RunButton.Enable = 'on';            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.s = app.Slider.Value;
            global image3 BW maskedImage
            [BW , maskedImage] = segmentation(image3.img_histeq, app.s);
            imshow(BW, 'Parent', app.UIAxes_3);            

            imshow(maskedImage, 'Parent', app.UIAxes_4);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw Image');
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.featparamDropDown = uidropdown(app.UIFigure);
            app.featparamDropDown.Enable = 'off';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'Test', 'Train'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Test';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Enable = 'off';
            app.ListBox.Value = {};
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Nomalized Image');
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Segmented Image_ BW');
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Segmented Image masked RGBImage');
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = myclassifier()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end